classdef game1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        PlayPanel       matlab.ui.container.Panel
        WordsLeftLabel  matlab.ui.control.Label
        SubmitButton    matlab.ui.control.Button
        EditField5      matlab.ui.control.EditField
        EditField4      matlab.ui.control.EditField
        EditField3      matlab.ui.control.EditField
        EditField2      matlab.ui.control.EditField
        EditField       matlab.ui.control.EditField
        Button5         matlab.ui.control.Button
        Button4         matlab.ui.control.Button
        Button3         matlab.ui.control.Button
        Button2         matlab.ui.control.Button
        Button          matlab.ui.control.Button
        GameModePanel   matlab.ui.container.Panel
        ReadyButton     matlab.ui.control.Button
        GameModeButton  matlab.ui.control.Button
        GameModeLabel   matlab.ui.control.Label
        WelcomePanel    matlab.ui.container.Panel
        StartButton     matlab.ui.control.Button
        WelcomeLabel    matlab.ui.control.Label
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.showGameModeScreen();
        end

        % Button pushed function: GameModeButton
        function GameModeButtonPushed(app, event)
            app.ReadyButton.Visible = 'on';
        end

        % Button pushed function: ReadyButton
        function ReadyButtonPushed(app, event)
            app.showFiveNormalScreen();
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            app.checkWord();
        end

        % Button pushed function: Button, Button2, Button3, Button4, 
        % ...and 1 other component
        function LetterTilesClicked(app, event)
            app.DraggingTile =(event.Source);
            set(app.UIFigure, 'WindowButtonMotionFcn', @(src,event)app.dragTile(event));
            set(app.UIFigure, 'WindowButtonUpFcn', @(src,event)app.dragTile(event));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcomePanel = uipanel(app.UIFigure);
            app.WelcomePanel.Title = 'Welcome Panel';
            app.UIAxes = uiaxes(app.WelcomePanel);
            title(app.UIAxes, 'WelcomeAxes')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.WelcomeLabel = uilabel(app.WelcomePanel);
            app.WelcomeLabel.Text = 'WelcomeLabel';
            app.StartButton = uibutton(app.WelcomePanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'StartButton';
            app.GameModePanel = uipanel(app.UIFigure);
            app.GameModePanel.Title = 'GameModePanel';
            app.GameModeLabel = uilabel(app.GameModePanel);
            app.GameModeLabel.Text = 'GameModeLabel';
            app.GameModeButton = uibutton(app.GameModePanel, 'push');
            app.GameModeButton.ButtonPushedFcn = createCallbackFcn(app, @GameModeButtonPushed, true);
            app.GameModeButton.Text = 'GameModeButton';
            app.ReadyButton = uibutton(app.GameModePanel, 'push');
            app.ReadyButton.ButtonPushedFcn = createCallbackFcn(app, @ReadyButtonPushed, true);
            app.ReadyButton.Text = 'ReadyButton';
            app.PlayPanel = uipanel(app.UIFigure);
            app.PlayPanel.Title = 'PlayPanel';
            app.Button = uibutton(app.PlayPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button.Tag = 'LetterTiles';
            app.Button2 = uibutton(app.PlayPanel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button2.Tag = 'LetterTiles';
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.PlayPanel, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button3.Tag = 'LetterTiles';
            app.Button3.Text = 'Button3';
            app.Button4 = uibutton(app.PlayPanel, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button4.Tag = 'LetterTiles';
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.PlayPanel, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button5.Tag = 'LetterTiles';
            app.Button5.Text = 'Button5';
            app.EditField = uieditfield(app.PlayPanel, 'text');
            app.EditField.Tag = 'WordSlots';
            app.EditField2 = uieditfield(app.PlayPanel, 'text');
            app.EditField2.Tag = 'WordSlots';
            app.EditField3 = uieditfield(app.PlayPanel, 'text');
            app.EditField3.Tag = 'WordSlots';
            app.EditField4 = uieditfield(app.PlayPanel, 'text');
            app.EditField4.Tag = 'WordSlots';
            app.EditField5 = uieditfield(app.PlayPanel, 'text');
            app.EditField5.Tag = 'WordSlots';
            app.SubmitButton = uibutton(app.PlayPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'SubmitButton';
            app.WordsLeftLabel = uilabel(app.PlayPanel);
            app.WordsLeftLabel.Text = 'WordsLeftLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end